classdef GUI_mod < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        thres_negEditFieldLabel   matlab.ui.control.Label
        thres_negEditField        matlab.ui.control.NumericEditField
        thres_weakEditFieldLabel  matlab.ui.control.Label
        thres_weakEditField       matlab.ui.control.NumericEditField
        t_readEditFieldLabel      matlab.ui.control.Label
        t_readEditField           matlab.ui.control.NumericEditField
        t_resultEditFieldLabel    matlab.ui.control.Label
        t_resultEditField         matlab.ui.control.NumericEditField
        ReadoutvaluesPanel        matlab.ui.container.Panel
        R1EditFieldLabel          matlab.ui.control.Label
        R1EditField               matlab.ui.control.NumericEditField
        R2EditFieldLabel          matlab.ui.control.Label
        R2EditField               matlab.ui.control.NumericEditField
        R3EditFieldLabel          matlab.ui.control.Label
        R3EditField               matlab.ui.control.NumericEditField
        R4EditFieldLabel          matlab.ui.control.Label
        R4EditField               matlab.ui.control.NumericEditField
        r_1EditFieldLabel         matlab.ui.control.Label
        r_1EditField              matlab.ui.control.NumericEditField
        r_2EditFieldLabel         matlab.ui.control.Label
        r_2EditField              matlab.ui.control.NumericEditField
        r_3EditFieldLabel         matlab.ui.control.Label
        r_3EditField              matlab.ui.control.NumericEditField
        r_4EditFieldLabel         matlab.ui.control.Label
        r_4EditField              matlab.ui.control.NumericEditField
        ResultsPanel              matlab.ui.container.Panel
        CTEditFieldLabel          matlab.ui.control.Label
        CTEditField               matlab.ui.control.NumericEditField
        NGEditFieldLabel          matlab.ui.control.Label
        NGEditField               matlab.ui.control.NumericEditField
        IC1EditFieldLabel         matlab.ui.control.Label
        IC1EditField              matlab.ui.control.NumericEditField
        CTLampLabel               matlab.ui.control.Label
        CTLamp                    matlab.ui.control.Lamp
        NGLampLabel               matlab.ui.control.Label
        NGLamp                    matlab.ui.control.Lamp
        ICLampLabel               matlab.ui.control.Label
        ICLamp                    matlab.ui.control.Lamp
        SetButton                 matlab.ui.control.Button
        ReadoutButton             matlab.ui.control.Button
        ExportButton              matlab.ui.control.Button
        FilenameEditFieldLabel    matlab.ui.control.Label
        FilenameEditField         matlab.ui.control.EditField
        ConnectButton             matlab.ui.control.Button
        ReadExportButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        %---CONSTANTS---
        const_no = 2 % threshold for NO
        const_weak = 4 % threshold for weak signal
        %---CONSTANTS END---
        
        iTime=300 % time for the readout
        i_CalculationWindow = 5 % the mean of the last n timepoints are taken for calculation
        filename = strcat('log',datestr(now,'dd.mm.yyyy'),'.xlsx')
        url = 'http://192.168.4.1'
        options = weboptions('RequestMethod','get','ArrayFormat','csv','ContentType','text')
        
        % variable arrays for the readout zones (set to default size):
        r1
        r2
        r3
        r4
        D1
        D2
        D3
        D4
        
        %variables for the results
        CT
        NG
        IC
    end
    
    methods (Access = private)
        
        function Initialization(app)
            data = webread(app.url,app.options);
            app.R1EditField.Value = str2num(data(1,31:33));
            app.R2EditField.Value = str2num(data(1,38:40));
            app.R3EditField.Value = str2num(data(1,45:47));
            app.R4EditField.Value = str2num(data(1,52:54));            
        end
        
        function RescaleArrays(app)
            app.r1 = zeros(1,app.iTime);
            app.r2 = zeros(1,app.iTime);
            app.r3= zeros(1,app.iTime);
            app.r4 = zeros(1,app.iTime);
            app.D1 = zeros(1,app.iTime);
            app.D2 = zeros(1,app.iTime);
            app.D3 = zeros(1,app.iTime);
            app.D4 = zeros(1,app.iTime);
        end
        
        function Readout(app)
            
            errors=0;
            success=0;
            test_duration=app.iTime;%seconds
            %start matlab timer
            tic;
            passed_time=toc;
            i=0;
            
            f = waitbar(i/test_duration,'Readout in progress');
            
            while passed_time<test_duration
                passed_time=toc;
                i=i+1;
                disp(strcat('passed time:',num2str(passed_time)));
                
                waitbar(i/test_duration,f,'Readout in progress');
                
                [data, success]=urlread('http://192.168.4.1');
                
                %error check
                %preset valid_string as negative response
                valid_string=0;
                if success==1 && length(data)==84
                    if strcmp(data(1:23),'PIC response: Hello! C=') && strcmp(data(83:84),' B')
                        valid_string=1;
                    end
                end
                
                if valid_string==1
                    disp(strcat('Readout nr.',num2str(i),'/',num2str(test_duration),':',data));
                    app.r1(i)=str2num(data(1,59:61));
                    app.r2(i)=str2num(data(1,66:68));
                    app.r3(i)=str2num(data(1,73:75));
                    app.r4(i)=str2num(data(1,80:82));
                    app.D1(i)=app.R1EditField.Value-app.r1(i);
                    app.D2(i)=app.R2EditField.Value-app.r2(i);
                    app.D3(i)=app.R3EditField.Value-app.r3(i);
                    app.D4(i)=app.R4EditField.Value-app.r4(i);
                    
                    app.r_1EditField.Value = app.r1(i);
                    app.r_2EditField.Value = app.r2(i);
                    app.r_3EditField.Value = app.r3(i);
                    app.r_4EditField.Value = app.r4(i);
                    
                    pause(1.200)
                else
                    errors=errors+1;
                    disp(['Data read error n.',num2str(errors),'/',num2str(i)])
                    %wait some ms
                    pause(.900)
                end                
            end
            
            if(ishandle(f))
                close(f);
            end
            
            disp(['Readout complete. Total number of Data read error: ',num2str(errors)]);
        end
        
        function UpdateVariables(app)
            app.const_no = app.thres_negEditField.Value; % threshold for NO
            app.const_weak = app.thres_weakEditField.Value; % threshold for weak signal
            app.iTime=app.t_readEditField.Value; % time for the readout
            app.i_CalculationWindow = app.t_resultEditField.Value; % the mean of the last n timepoints are taken for calculation
            app.filename = app.FilenameEditField.Value;
        end
        
        function UpdateResults(app)
            %Result (take average of last n values) - note: the orientation might be
            %wrong for now, but I'll correct it later if it is (I checked your drawing
            %and compared it to the device but still)
            CT_val= mean(app.D4(1:1:end-app.i_CalculationWindow));
            NG_val= mean(app.D3(1:1:end-app.i_CalculationWindow));
            IC_val= mean(app.D1(1:1:end-app.i_CalculationWindow));
            
            app.CTEditField.Value = CT_val;
            app.NGEditField.Value = NG_val;
            app.IC1EditField.Value = IC_val;
            
            if(CT_val>app.const_no)
                if(CT_val>app.const_weak)
                    app.CT='pos';
                    app.CTLamp.Color = 'red';
                else
                    app.CT='weak';
                    app.CTLamp.Color = 'yellow';
                end
            else
                app.CT='no';
                app.CTLamp.Color = 'green';
            end
            
            if(NG_val>app.const_no)
                if(NG_val>app.const_weak)
                    app.NG='pos';
                    app.NGLamp.Color = 'red';
                else
                    app.NG='weak';
                    app.NGLamp.Color = 'yellow';
                end
            else
                app.NG='no';
                app.NGLamp.Color = 'green';
            end
            
            if(IC_val>app.const_no)
                if(IC_val>app.const_weak)
                    app.IC='pos';
                    app.ICLamp.Color = 'green';
                else
                    app.IC='weak';
                    app.ICLamp.Color = 'yellow';
                end
            else
                app.IC='no';
                app.ICLamp.Color = 'red';
            end
            
        end
        
        function SaveToExcel(app)
            disp(['Exporting data']);
            xlswrite(app.filename,{'D1'},'Sheet1','A1');
            xlswrite(app.filename,{'D2'},'Sheet1','B1');
            xlswrite(app.filename,{'D3'},'Sheet1','C1');
            xlswrite(app.filename,{'D4'},'Sheet1','D1');
            
            xlswrite(app.filename,{'R1'},'Sheet1','E1');
            xlswrite(app.filename,{'R2'},'Sheet1','F1');
            xlswrite(app.filename,{'R3'},'Sheet1','G1');
            xlswrite(app.filename,{'R4'},'Sheet1','H1');
            
            xlswrite(app.filename,{'r1'},'Sheet1','I1');
            xlswrite(app.filename,{'r2'},'Sheet1','J1');
            xlswrite(app.filename,{'r3'},'Sheet1','K1');
            xlswrite(app.filename,{'r4'},'Sheet1','L1');
            
            xlswrite(app.filename,app.D1','Sheet1','A2');
            xlswrite(app.filename,app.D2','Sheet1','B2');
            xlswrite(app.filename,app.D3','Sheet1','C2');
            xlswrite(app.filename,app.D4','Sheet1','D2');
            
            xlswrite(app.filename,app.R1EditField.Value,'Sheet1','E2');
            xlswrite(app.filename,app.R2EditField.Value,'Sheet1','F2');
            xlswrite(app.filename,app.R3EditField.Value,'Sheet1','G2');
            xlswrite(app.filename,app.R4EditField.Value,'Sheet1','H2');
            
            xlswrite(app.filename,app.r1','Sheet1','I2');
            xlswrite(app.filename,app.r2','Sheet1','J2');
            xlswrite(app.filename,app.r3','Sheet1','K2');
            xlswrite(app.filename,app.r4','Sheet1','L2');
            disp(['Export successful']);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.thres_negEditField.Value = app.const_no;
            app.thres_weakEditField.Value = app.const_weak;
            app.FilenameEditField.Value = app.filename;
            app.t_readEditField.Value = app.iTime;
            app.t_resultEditField.Value = app.i_CalculationWindow;
            RescaleArrays(app);
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            RescaleArrays(app);
            UpdateVariables(app);
        end

        % Button pushed function: ReadoutButton
        function ReadoutButtonPushed(app, event)
            Readout(app);
            UpdateResults(app);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            SaveToExcel(app);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            Initialization(app);
        end

        % Button pushed function: ReadExportButton
        function ReadExportButtonPushed(app, event)
            Initialization(app);
            Readout(app);
            UpdateResults(app);
            SaveToExcel(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.thres_negEditFieldLabel = uilabel(app.UIFigure);
            app.thres_negEditFieldLabel.Text = 'thres_neg';
            app.thres_negEditField = uieditfield(app.UIFigure, 'numeric');
            app.thres_weakEditFieldLabel = uilabel(app.UIFigure);
            app.thres_weakEditFieldLabel.Text = 'thres_weak';
            app.thres_weakEditField = uieditfield(app.UIFigure, 'numeric');
            app.t_readEditFieldLabel = uilabel(app.UIFigure);
            app.t_readEditFieldLabel.Text = 't_read';
            app.t_readEditField = uieditfield(app.UIFigure, 'numeric');
            app.t_resultEditFieldLabel = uilabel(app.UIFigure);
            app.t_resultEditFieldLabel.Text = 't_result';
            app.t_resultEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReadoutvaluesPanel = uipanel(app.UIFigure);
            app.ReadoutvaluesPanel.Title = 'Readout values';
            app.R1EditFieldLabel = uilabel(app.ReadoutvaluesPanel);
            app.R1EditFieldLabel.Text = 'R1';
            app.R1EditField = uieditfield(app.ReadoutvaluesPanel, 'numeric');
            app.R1EditField.Editable = 'off';
            app.R2EditFieldLabel = uilabel(app.ReadoutvaluesPanel);
            app.R2EditFieldLabel.Text = 'R2';
            app.R2EditField = uieditfield(app.ReadoutvaluesPanel, 'numeric');
            app.R2EditField.Editable = 'off';
            app.R3EditFieldLabel = uilabel(app.ReadoutvaluesPanel);
            app.R3EditFieldLabel.Text = 'R3';
            app.R3EditField = uieditfield(app.ReadoutvaluesPanel, 'numeric');
            app.R3EditField.Editable = 'off';
            app.R4EditFieldLabel = uilabel(app.ReadoutvaluesPanel);
            app.R4EditFieldLabel.Text = 'R4';
            app.R4EditField = uieditfield(app.ReadoutvaluesPanel, 'numeric');
            app.R4EditField.Editable = 'off';
            app.r_1EditFieldLabel = uilabel(app.ReadoutvaluesPanel);
            app.r_1EditFieldLabel.Text = 'r_1';
            app.r_1EditField = uieditfield(app.ReadoutvaluesPanel, 'numeric');
            app.r_1EditField.Editable = 'off';
            app.r_2EditFieldLabel = uilabel(app.ReadoutvaluesPanel);
            app.r_2EditFieldLabel.Text = 'r_2';
            app.r_2EditField = uieditfield(app.ReadoutvaluesPanel, 'numeric');
            app.r_2EditField.Editable = 'off';
            app.r_3EditFieldLabel = uilabel(app.ReadoutvaluesPanel);
            app.r_3EditFieldLabel.Text = 'r_3';
            app.r_3EditField = uieditfield(app.ReadoutvaluesPanel, 'numeric');
            app.r_3EditField.Editable = 'off';
            app.r_4EditFieldLabel = uilabel(app.ReadoutvaluesPanel);
            app.r_4EditFieldLabel.Text = 'r_4';
            app.r_4EditField = uieditfield(app.ReadoutvaluesPanel, 'numeric');
            app.r_4EditField.Editable = 'off';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.CTEditFieldLabel = uilabel(app.ResultsPanel);
            app.CTEditFieldLabel.Text = 'CT';
            app.CTEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.CTEditField.Editable = 'off';
            app.NGEditFieldLabel = uilabel(app.ResultsPanel);
            app.NGEditFieldLabel.Text = 'NG';
            app.NGEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.NGEditField.Editable = 'off';
            app.IC1EditFieldLabel = uilabel(app.ResultsPanel);
            app.IC1EditFieldLabel.Text = 'IC1';
            app.IC1EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.IC1EditField.Editable = 'off';
            app.CTLampLabel = uilabel(app.ResultsPanel);
            app.CTLampLabel.Text = 'CT';
            app.CTLamp = uilamp(app.ResultsPanel);
            app.NGLampLabel = uilabel(app.ResultsPanel);
            app.NGLampLabel.Text = 'NG';
            app.NGLamp = uilamp(app.ResultsPanel);
            app.ICLampLabel = uilabel(app.ResultsPanel);
            app.ICLampLabel.Text = 'IC';
            app.ICLamp = uilamp(app.ResultsPanel);
            app.SetButton = uibutton(app.UIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.ReadoutButton = uibutton(app.UIFigure, 'push');
            app.ReadoutButton.ButtonPushedFcn = createCallbackFcn(app, @ReadoutButtonPushed, true);
            app.ReadoutButton.Text = 'Readout!';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ReadExportButton = uibutton(app.UIFigure, 'push');
            app.ReadExportButton.ButtonPushedFcn = createCallbackFcn(app, @ReadExportButtonPushed, true);
            app.ReadExportButton.Text = 'Read&Export';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_mod

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end